classdef Radar_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Rmax_image                      matlab.ui.control.Image
        pt_image                        matlab.ui.control.Image
        gr_image                        matlab.ui.control.Image
        gt_image                        matlab.ui.control.Image
        Lt_image                        matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        ResultLabel                     matlab.ui.control.Label
        result_unit                     matlab.ui.control.Label
        KmLabel                         matlab.ui.control.Label
        TargetRangeEditField            matlab.ui.control.NumericEditField
        TargetRangeEditFieldLabel       matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        ParameterDropDownLabel          matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        dBLabel_7                       matlab.ui.control.Label
        dBLabel_6                       matlab.ui.control.Label
        dBLabel_5                       matlab.ui.control.Label
        dBLabel_4                       matlab.ui.control.Label
        dBLabel_3                       matlab.ui.control.Label
        OtherLossesEditField            matlab.ui.control.NumericEditField
        OtherLossesEditFieldLabel       matlab.ui.control.Label
        PolarizationLossEditField       matlab.ui.control.NumericEditField
        PolarizationLossEditFieldLabel  matlab.ui.control.Label
        AtmosphericLossEditField        matlab.ui.control.NumericEditField
        AtmosphericLossEditFieldLabel   matlab.ui.control.Label
        MismatchLossEditField           matlab.ui.control.NumericEditField
        MismatchLossEditFieldLabel      matlab.ui.control.Label
        TransmissionLineLossesEditField  matlab.ui.control.NumericEditField
        TransmissionLineLossesEditFieldLabel  matlab.ui.control.Label
        RADARSIMULATORLabel             matlab.ui.control.Label
        MessageLabel                    matlab.ui.control.Label
        pWLabel                         matlab.ui.control.Label
        MinDetectablePowerEditField     matlab.ui.control.NumericEditField
        MinDetectablePowerEditFieldLabel  matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        RadarcrosssecEditField          matlab.ui.control.NumericEditField
        RadarcrosssecEditFieldLabel     matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        ReceiverGainGrEditField         matlab.ui.control.NumericEditField
        ReceiverGainGrEditFieldLabel    matlab.ui.control.Label
        TransmittedGainGtEditField      matlab.ui.control.NumericEditField
        TransmittedGainGtEditFieldLabel  matlab.ui.control.Label
        TransmittedPowerPtEditField     matlab.ui.control.NumericEditField
        TransmittedPowerPtEditFieldLabel  matlab.ui.control.Label
        Version12Label                  matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExampleRadarButton              matlab.ui.control.Button
        HistoryButton                   matlab.ui.control.Button
        FeaturesButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Pt = app.TransmittedPowerPtEditField.Value;
            Gt_dB = app.TransmittedGainGtEditField.Value;
            Gr_dB = app.ReceiverGainGrEditField.Value;
            lambda = app.WavelengthEditField.Value;
            sigma = app.RadarcrosssecEditField.Value;
            Pmin = app.MinDetectablePowerEditField.Value * 1e-12;
            Ltransmission_dB = app.TransmissionLineLossesEditField.Value;
            Lmismatch_dB = app.MismatchLossEditField.Value;
            Latmospheric_dB = app.AtmosphericLossEditField.Value;
            Lpolarization_dB = app.PolarizationLossEditField.Value;
            Lother_dB = app.OtherLossesEditField.Value;
            Range=app.TargetRangeEditField.Value *1000;

            % switch case for dropdown menu

            switch app.ParameterDropDown.Value
                case "Target Range"       

                    % convert dB to linear scale
                    Gt = 10^(Gt_dB / 10);
                    Gr = 10^(Gr_dB / 10);
            
                    Ltransmission = 10^(Ltransmission_dB / 10);
                    Lmismatch = 10^(Lmismatch_dB / 10);
                    Latmospheric = 10^(Latmospheric_dB / 10);
                    Lpolarization = 10^(Lpolarization_dB / 10);
                    Lother = 10^(Lother_dB / 10);
            
                    % total losses
                    Ltotal = Ltransmission * Lmismatch * Latmospheric * Lpolarization * Lother;
            
                    % radar range equation
                    Rmax = ((Pt * Gt * Gr * lambda^2 * sigma) / ((4 * pi)^3 * Pmin * Ltotal))^(1/4);
        
                    % display the result
                    app.ResultEditField.Value=(Rmax/1000);
                    app.ResultLabel.Text='Target Range';
                    app.result_unit.Text='Km';

                case "Peak Transmit Power"
                    % Convert dB to linear scale
                    Gt = 10^(Gt_dB / 10);
                    Gr = 10^(Gr_dB / 10);
                    Ltransmission = 10^(Ltransmission_dB / 10);
                    Lmismatch = 10^(Lmismatch_dB / 10);
                    Latmospheric = 10^(Latmospheric_dB / 10);
                    Lpolarization = 10^(Lpolarization_dB / 10);
                    Lother = 10^(Lother_dB / 10);
        
                    % Total losses
                    Ltotal = Ltransmission * Lmismatch * Latmospheric * Lpolarization * Lother;
   
                    % Radar equation for Pt
                    Pt_calculated = (Range^4 * (4 * pi)^3 * Pmin * Ltotal) / (Gt * Gr * lambda^2 * sigma);
        
                    % Display the result
                    app.ResultEditField.Value = Pt_calculated;
                    app.ResultLabel.Text = 'Peak Transmit Power';
                    app.result_unit.Text = 'W';

                case "General Losses"
                    % Convert dB to linear scale
                    Gt = 10^(Gt_dB / 10);
                    Gr = 10^(Gr_dB / 10);
                
                    % total losses 
                    Ltotal = (Pt * Gt * Gr * lambda^2 * sigma) / ((4 * pi)^3 * Pmin * Range^4);
                
                    % Convert total losses to dB
                    Ltotal_dB = 10 * log10(Ltotal);
                
                    % Display the result
                    app.ResultEditField.Value = Ltotal_dB;
                    app.ResultLabel.Text = 'Total Losses';
                    app.result_unit.Text = 'dB';

                case "Transmitter Gain"
                    % Convert dB to linear scale 
                    Gr = 10^(Gr_dB / 10);
                    
                    % Convert total losses from dB to linear scale
                    Ltransmission = 10^(Ltransmission_dB / 10);
                    Lmismatch = 10^(Lmismatch_dB / 10);
                    Latmospheric = 10^(Latmospheric_dB / 10);
                    Lpolarization = 10^(Lpolarization_dB / 10);
                    Lother = 10^(Lother_dB / 10);
                    
                    % Total losses in linear scale
                    Ltotal = Ltransmission * Lmismatch * Latmospheric * Lpolarization * Lother;
                
                    % Radar equation for Gt
                    Gt = ((4 * pi)^3 * Pmin * Ltotal * Range^4) / (Pt * Gr * lambda^2 * sigma);
                
                    % Convert transmitter gain to dB
                    Gt_dB = 10 * log10(Gt);
                
                    % Display the result
                    app.ResultEditField.Value = Gt_dB;
                    app.ResultLabel.Text = 'Transmitter Gain';
                    app.result_unit.Text = 'dB';

                case "Receiver Gain"
                    % Convert dB to linear scale 
                    Gt = 10^(Gt_dB / 10);
                    
                    % Convert total losses from dB to linear scale
                    Ltransmission = 10^(Ltransmission_dB / 10);
                    Lmismatch = 10^(Lmismatch_dB / 10);
                    Latmospheric = 10^(Latmospheric_dB / 10);
                    Lpolarization = 10^(Lpolarization_dB / 10);
                    Lother = 10^(Lother_dB / 10);
                    
                    % Total losses in linear scale
                    Ltotal = Ltransmission * Lmismatch * Latmospheric * Lpolarization * Lother;
                
                    % Radar equation for Gr
                    Gr = ((4 * pi)^3 * Pmin * Ltotal * Range^4) / (Pt * Gt * lambda^2 * sigma);
                
                    % Convert receiver gain to dB
                    Gr_dB = 10 * log10(Gr);
                
                    % Display the result
                    app.ResultEditField.Value = Gr_dB;
                    app.ResultLabel.Text = 'Receiver Gain';
                    app.result_unit.Text = 'dB';

            end


        end

        % Button pushed function: ExampleRadarButton
        function ExampleRadarButtonPushed(app, event)
            app.TransmittedPowerPtEditField.Value = 1000; 
            app.TransmittedGainGtEditField.Value = 30;
            app.ReceiverGainGrEditField.Value = 30; 
            app.WavelengthEditField.Value = 0.03; 
            app.RadarcrosssecEditField.Value = 1; 
            app.MinDetectablePowerEditField.Value = 1; 
            app.TransmissionLineLossesEditField.Value = 2; 
            app.MismatchLossEditField.Value = 1;
            app.AtmosphericLossEditField.Value = 1; 
            app.PolarizationLossEditField.Value = 0.5;
            app.OtherLossesEditField.Value = 1;
            app.TargetRangeEditField.Value = 3.3624298258112204;
            %uialert(app.UIFigure, 'Example values have been set.', 'Press "Calculate" to see Results');
            app.MessageLabel.Text = 'Example values have been set. Press "Calculate" to see result.';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close(app.UIFigure);
            Radar_simulator;            
        end

        % Button pushed function: FeaturesButton
        function FeaturesButtonPushed(app, event)
            
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            app.history = {};
            % Create a struct to store current values
            currentValues.Pt = app.TransmittedPowerPtEditField.Value;
            currentValues.Gt = app.TransmittedGainGtEditField.Value;
            currentValues.Gr = app.ReceiverGainGrEditField.Value;
            currentValues.lambda = app.WavelengthEditField.Value;
            currentValues.sigma = app.RadarcrosssecEditField.Value;
            currentValues.Pmin = app.MinDetectablePowerEditField.Value;
            currentValues.Ltransmission = app.TransmissionLineLossesEditField.Value;
            currentValues.Lmismatch = app.MismatchLossEditField.Value;
            currentValues.Latmospheric = app.AtmosphericLossEditField.Value;
            currentValues.Lpolarization = app.PolarizationLossEditField.Value;
            currentValues.Lother = app.OtherLossesEditField.Value;
            currentValues.Range = app.TargetRangeEditField.Value;
        
            % Add current values to history
            app.history{end+1} = currentValues;
        
            % Optionally, display the history
            disp('History:');
            disp(app.history);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;
            switch value
                case "Target Range"
                    app.TargetRangeEditField.Editable="off";
                    app.TransmittedGainGtEditField.Editable="on";
                    app.ReceiverGainGrEditField.Editable="on";
                    app.TransmittedPowerPtEditField.Editable="on";
                    app.TransmissionLineLossesEditField.Editable="on";
                    app.MismatchLossEditField.Editable="on";
                    app.AtmosphericLossEditField.Editable="on";
                    app.PolarizationLossEditField.Editable="on";
                    app.OtherLossesEditField.Editable="on";
                    % images
                    app.Rmax_image.Visible="on";
                    app.pt_image.Visible="off";
                    app.gt_image.Visible="off";
                    app.gr_image.Visible="off";
                    app.Lt_image.Visible="off";


                case "Peak Transmit Power"
                    app.TargetRangeEditField.Editable="on";
                    app.TransmittedGainGtEditField.Editable="on";
                    app.ReceiverGainGrEditField.Editable="on";
                    app.TransmittedPowerPtEditField.Editable="off";
                    app.TransmissionLineLossesEditField.Editable="on";
                    app.MismatchLossEditField.Editable="on";
                    app.AtmosphericLossEditField.Editable="on";
                    app.PolarizationLossEditField.Editable="on";
                    app.OtherLossesEditField.Editable="on";
                    % images
                    app.Rmax_image.Visible="off";
                    app.pt_image.Visible="on";
                    app.gt_image.Visible="off";
                    app.gr_image.Visible="off";
                    app.Lt_image.Visible="off";

                case "Transmitter Gain"
                    app.TargetRangeEditField.Editable="on";
                    app.TransmittedGainGtEditField.Editable="off";
                    app.ReceiverGainGrEditField.Editable="on";
                    app.TransmittedPowerPtEditField.Editable="on";
                    app.TransmissionLineLossesEditField.Editable="on";
                    app.MismatchLossEditField.Editable="on";
                    app.AtmosphericLossEditField.Editable="on";
                    app.PolarizationLossEditField.Editable="on";
                    app.OtherLossesEditField.Editable="on";
                     % images
                    app.Rmax_image.Visible="off";
                    app.pt_image.Visible="off";
                    app.gt_image.Visible="on";
                    app.gr_image.Visible="off";
                    app.Lt_image.Visible="off";
                case "Receiver Gain"
                    app.TargetRangeEditField.Editable="on";
                    app.TransmittedGainGtEditField.Editable="on";
                    app.ReceiverGainGrEditField.Editable="off";
                    app.TransmittedPowerPtEditField.Editable="on";
                    app.TransmissionLineLossesEditField.Editable="on";
                    app.MismatchLossEditField.Editable="on";
                    app.AtmosphericLossEditField.Editable="on";
                    app.PolarizationLossEditField.Editable="on";
                    app.OtherLossesEditField.Editable="on";
                     % images
                    app.Rmax_image.Visible="off";
                    app.pt_image.Visible="off";
                    app.gt_image.Visible="off";
                    app.gr_image.Visible="on";
                    app.Lt_image.Visible="off";
                case "General Losses"
                    app.TargetRangeEditField.Editable="on";
                    app.TransmittedGainGtEditField.Editable="on";
                    app.ReceiverGainGrEditField.Editable="on";
                    app.TransmittedPowerPtEditField.Editable="on";
                    app.TransmissionLineLossesEditField.Editable="off";
                    app.MismatchLossEditField.Editable="off";
                    app.AtmosphericLossEditField.Editable="off";
                    app.PolarizationLossEditField.Editable="off";
                    app.OtherLossesEditField.Editable="off";
                     % images
                    app.Rmax_image.Visible="off";
                    app.pt_image.Visible="off";
                    app.gt_image.Visible="off";
                    app.gr_image.Visible="off";
                    app.Lt_image.Visible="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FeaturesButton = uibutton(app.UIFigure, 'push');
            app.FeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @FeaturesButtonPushed, true);
            app.FeaturesButton.Text = 'Features';
            app.HistoryButton = uibutton(app.UIFigure, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History';
            app.ExampleRadarButton = uibutton(app.UIFigure, 'push');
            app.ExampleRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ExampleRadarButtonPushed, true);
            app.ExampleRadarButton.Text = 'Example Radar';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Version12Label = uilabel(app.UIFigure);
            app.Version12Label.Text = 'Version 1.2';
            app.TransmittedPowerPtEditFieldLabel = uilabel(app.UIFigure);
            app.TransmittedPowerPtEditFieldLabel.Text = 'Transmitted Power (Pt)';
            app.TransmittedPowerPtEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmittedGainGtEditFieldLabel = uilabel(app.UIFigure);
            app.TransmittedGainGtEditFieldLabel.Text = 'Transmitted Gain (Gt)';
            app.TransmittedGainGtEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceiverGainGrEditFieldLabel = uilabel(app.UIFigure);
            app.ReceiverGainGrEditFieldLabel.Text = 'Receiver Gain (Gr)';
            app.ReceiverGainGrEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthEditFieldLabel.Text = 'Wavelength (λ)';
            app.WavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadarcrosssecEditFieldLabel = uilabel(app.UIFigure);
            app.RadarcrosssecEditFieldLabel.Text = 'Radar cross-sec (σ)';
            app.RadarcrosssecEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.MinDetectablePowerEditFieldLabel = uilabel(app.UIFigure);
            app.MinDetectablePowerEditFieldLabel.Text = 'Min Detectable Power';
            app.MinDetectablePowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.pWLabel = uilabel(app.UIFigure);
            app.pWLabel.Text = 'pW';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message';
            app.RADARSIMULATORLabel = uilabel(app.UIFigure);
            app.RADARSIMULATORLabel.Text = 'RADAR SIMULATOR';
            app.TransmissionLineLossesEditFieldLabel = uilabel(app.UIFigure);
            app.TransmissionLineLossesEditFieldLabel.Text = {'Transmission Line Losses'; ''};
            app.TransmissionLineLossesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MismatchLossEditFieldLabel = uilabel(app.UIFigure);
            app.MismatchLossEditFieldLabel.Text = 'Mismatch Loss';
            app.MismatchLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.AtmosphericLossEditFieldLabel = uilabel(app.UIFigure);
            app.AtmosphericLossEditFieldLabel.Text = 'Atmospheric Loss';
            app.AtmosphericLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolarizationLossEditFieldLabel = uilabel(app.UIFigure);
            app.PolarizationLossEditFieldLabel.Text = 'Polarization Loss';
            app.PolarizationLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.OtherLossesEditFieldLabel = uilabel(app.UIFigure);
            app.OtherLossesEditFieldLabel.Text = 'Other Losses';
            app.OtherLossesEditField = uieditfield(app.UIFigure, 'numeric');
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = 'dB';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = 'dB';
            app.dBLabel_7 = uilabel(app.UIFigure);
            app.dBLabel_7.Text = 'dB';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParameterDropDownLabel = uilabel(app.UIFigure);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.UIFigure);
            app.ParameterDropDown.Items = {'Select', 'Target Range', 'Peak Transmit Power', 'Transmitter Gain', 'Receiver Gain', 'General Losses'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = 'Select';
            app.TargetRangeEditFieldLabel = uilabel(app.UIFigure);
            app.TargetRangeEditFieldLabel.Text = 'Target Range';
            app.TargetRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.KmLabel = uilabel(app.UIFigure);
            app.KmLabel.Text = 'Km';
            app.result_unit = uilabel(app.UIFigure);
            app.result_unit.Text = 'Unit';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2';
            app.Lt_image = uiimage(app.UIFigure);
            app.Lt_image.ImageSource = fullfile(pathToMLAPP, 'range simulator', 'total losses.png');
            app.gt_image = uiimage(app.UIFigure);
            app.gt_image.ImageSource = fullfile(pathToMLAPP, 'range simulator', 'transmiter gain.png');
            app.gr_image = uiimage(app.UIFigure);
            app.gr_image.ImageSource = fullfile(pathToMLAPP, 'range simulator', 'receiver gain.png');
            app.pt_image = uiimage(app.UIFigure);
            app.pt_image.ImageSource = fullfile(pathToMLAPP, 'range simulator', 'Peak transmitted power.png');
            app.Rmax_image = uiimage(app.UIFigure);
            app.Rmax_image.ImageSource = fullfile(pathToMLAPP, 'range simulator', 'Rmax.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Radar_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end